classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        HeightEditField       matlab.ui.control.NumericEditField
        HeightEditFieldLabel  matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
        StartButton           matlab.ui.control.Button
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function Start(app, event)
            clear; clc; clf; close all;
            a = arduino("/dev/tty.usbserial-AQ02T0FB", "Nano3", "Libraries", 'Ultrasonic');
            ultrasonicObj = ultrasonic(a, "D3", "D2");

            distance = [];
            height = app.HeightEditField;
            r_max = 1;
            speedRPM = 10;
            vertical_speed = 0.1*speedRPM;
            angular_speed = 0.1*speedRPM;
            t_max = height/vertical_speed;
            for trial = 1 : 0.04 : t_max
                data = readDistance(ultrasonicObj);
                if data > r_max
                    data = 0;
                else
                    data = r_max - data;
                end
                distance = [distance data];
                writeDigitalPin(a, 'D8', 1);
                writeDigitalPin(a, 'D9', 1);
                writeDigitalPin(a, 'D10', 0);
                writeDigitalPin(a, 'D11', 0);
                pause(0.001);
                writeDigitalPin(a, 'D8', 0);
                writeDigitalPin(a, 'D9', 1);
                writeDigitalPin(a, 'D10', 1);
                writeDigitalPin(a, 'D11', 0);
                pause(0.001);
                writeDigitalPin(a, 'D8', 0);
                writeDigitalPin(a, 'D9', 0);
                writeDigitalPin(a, 'D10', 1);
                writeDigitalPin(a, 'D11', 1);
                pause(0.001);
                writeDigitalPin(a, 'D8', 1);
                writeDigitalPin(a, 'D9', 0);
                writeDigitalPin(a, 'D10', 0);
                writeDigitalPin(a, 'D11', 1);
                pause(0.001); 
            end
            theta = t.*angular_speed;
            x = r.*cos(theta);
            y = r.*sin(theta);
            z = t.*vertical_speed;
            scatter3(app.UIAxes, x, y, z)
            d = ceil(2*pi/angular_speed);
            X = [x(1:t_max-d)
                x(d+1:t_max)];
            Y = [y(1:t_max-d)
                y(d+1:t_max)];
            Z = [z(1:t_max-d)
                z(d+1:t_max)];
            surf(app.UIAxes_2, X, Y, Z)
        end

        % Button pushed function: ResetButton
        function Reset(app, event)
            clear; clc; clf; close all;
            a = arduino("/dev/tty.usbserial-AQ02T0FB", "Nano3", "Libraries", 'Ultrasonic');
            height = app.HeightEditField;
            t_max = height/vertical_speed;
            for trial = 1 : 0.004 : t_max
                writeDigitalPin(a, 'D8', 1);
                writeDigitalPin(a, 'D9', 0);
                writeDigitalPin(a, 'D10', 0);
                writeDigitalPin(a, 'D11', 1);
                pause(0.001);
                writeDigitalPin(a, 'D8', 0);
                writeDigitalPin(a, 'D9', 0);
                writeDigitalPin(a, 'D10', 1);
                writeDigitalPin(a, 'D11', 1);
                pause(0.001);
                writeDigitalPin(a, 'D8', 0);
                writeDigitalPin(a, 'D9', 1);
                writeDigitalPin(a, 'D10', 1);
                writeDigitalPin(a, 'D11', 0);
                pause(0.001);
                writeDigitalPin(a, 'D8', 1);
                writeDigitalPin(a, 'D9', 1);
                writeDigitalPin(a, 'D10', 0);
                writeDigitalPin(a, 'D11', 0);
                pause(0.001);
            end
        end

        % Value changed function: HeightEditField
        function Height(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Scatter Plot')
            xlabel(app.UIAxes, 'x/dm')
            ylabel(app.UIAxes, 'y/dm')
            zlabel(app.UIAxes, 'z/dm')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Surface Plot')
            xlabel(app.UIAxes_2, 'x/dm')
            ylabel(app.UIAxes_2, 'y/dm')
            zlabel(app.UIAxes_2, 'z/dm')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.ResetButton.Text = 'Reset';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @Height, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end